classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AddDoctorButton                 matlab.ui.control.Button
        AddPatientButton                matlab.ui.control.Button
        NameEditFieldLabel              matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        SurnameEditFieldLabel           matlab.ui.control.Label
        SurnameEditField                matlab.ui.control.EditField
        PersonalNumberEditFieldLabel    matlab.ui.control.Label
        PersonalNumberEditField         matlab.ui.control.NumericEditField
        DateofinfectionLabel            matlab.ui.control.Label
        DateofinfectionDatePicker       matlab.ui.control.DatePicker
        patientsTable                   matlab.ui.control.Table
        doctorsTable                    matlab.ui.control.Table
        PatientsLabel                   matlab.ui.control.Label
        DoctorsLabel                    matlab.ui.control.Label
        NameEditField_2Label            matlab.ui.control.Label
        NameEditFieldDoctor             matlab.ui.control.EditField
        SurnameEditField_2Label         matlab.ui.control.Label
        SurnameEditFieldDoctor          matlab.ui.control.EditField
        PersonalNumberEditField_2Label  matlab.ui.control.Label
        PersonalNumberEditFieldDoctor   matlab.ui.control.NumericEditField
        CurePatientButton               matlab.ui.control.Button
        PersonalNumberDoctorEditFieldLabel  matlab.ui.control.Label
        PersonalNumberDoctorEditField   matlab.ui.control.NumericEditField
        PersonalNumberPatientEditFieldLabel  matlab.ui.control.Label
        PersonalNumberPatientEditField  matlab.ui.control.NumericEditField
        InfoindateintervalButton        matlab.ui.control.Button
        DatefromDatePickerLabel         matlab.ui.control.Label
        DatefromDatePicker              matlab.ui.control.DatePicker
        DatetoDatePickerLabel           matlab.ui.control.Label
        DatetoDatePicker                matlab.ui.control.DatePicker
        InfoTextAreaLabel               matlab.ui.control.Label
        InfoTextArea                    matlab.ui.control.TextArea
        SavetofileButton                matlab.ui.control.Button
        LoadfromFileButton              matlab.ui.control.Button
        SubmitaddpatientButton          matlab.ui.control.Button
        SubmitdoctorButton              matlab.ui.control.Button
        SubmitofcuredButton             matlab.ui.control.Button
        GetInfoButton                   matlab.ui.control.Button
        SubmitinfoButton                matlab.ui.control.Button
        plotPatients                    matlab.ui.control.UIAxes
        plotDoctors                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        covidManager
    end
    
    methods (Access = private)
        
        function showPatients(app)
            oldData = get(app.patientsTable,'Data');
            tableOutput = {4};
            patients = app.covidManager.patients;
            
            tableOutput{1} = patients(length(patients)).name;
            tableOutput{2} = patients(length(patients)).surname;
            tableOutput{3} = patients(length(patients)).personalNumber;
            tableOutput{4} = datestr(patients(length(patients)).date);
                       
            newData = [oldData; tableOutput]; 
            set(app.patientsTable,'Data',newData);
        end
        
        function showDoctors(app)
            oldData = get(app.doctorsTable,'Data');
            tableOutput = {5};
            doctors = app.covidManager.doctors;
            
            tableOutput{1} = doctors(length(doctors)).name;
            tableOutput{2} = doctors(length(doctors)).surname;
            tableOutput{3} = doctors(length(doctors)).personalNumber;
            tableOutput{4} = doctors(length(doctors)).numberOfCured;
            tableOutput{5} = doctors(length(doctors)).bonus;
            
            newData = [oldData; tableOutput]; 
            set(app.doctorsTable,'Data',newData);
        end
        
        function showAll(app)
            set(app.doctorsTable,'Data',{});
            tableOutput = {5};
            doctors = app.covidManager.doctors;
            for index = 1: length(doctors)
                oldData = get(app.doctorsTable,'Data');
                tableOutput{1} = doctors(index).name;
                tableOutput{2} = doctors(index).surname;
                tableOutput{3} = doctors(index).personalNumber;
                tableOutput{4} = doctors(index).numberOfCured;
                tableOutput{5} = doctors(index).bonus;
                newData = [oldData; tableOutput]; 
                set(app.doctorsTable,'Data',newData);  
            end
            
            
            set(app.patientsTable,'Data',{});
            tableOutputP = {4};
            patients = app.covidManager.patients;
            disp(patients);
            for index = 1: length(patients)
                if(patients(index).positive)
                    oldDataP = get(app.patientsTable,'Data');
                    tableOutputP{1} = patients(index).name;
                    tableOutputP{2} = patients(index).surname;
                    tableOutputP{3} = patients(index).personalNumber;
                    tableOutputP{4} = datestr(patients(index).date);
                    newDataP = [oldDataP; tableOutputP]; 
                    set(app.patientsTable,'Data',newDataP);
                end
            end           
        end
        
        function updatePlotPatients(app)
            x = getDates(app.covidManager);
            y = getNumbersOfCovid(app.covidManager);
            plot(app.plotPatients, x,y);
        end
        
        function updatePlotDoctors(app)
            x = getNumbersOfBonus(app.covidManager);
            values = unique(x);
            X = categorical(x, values);
            histogram(app.plotDoctors, X, 'BarWidth',0.5)
        end
        
        function isValid = isValidInput(~, paName, paSurname, paPersonalNumber)
            isValid  = true;
            if(isempty(paName) ||  isempty(paSurname)) 
                msgbox("Empty input");
                isValid = false;
            end
            if(mod(paPersonalNumber,11) == 0 && paPersonalNumber < 0)
                msgbox("Invalid Personal Number");
                isValid = false;
            end
            
            if(mod(paPersonalNumber,11) ~= 0)
                msgbox("Invalid Personal Number");
                isValid = false;
            end
        end
        
        function setPatientVisibility(app, visibility)
            app.NameEditField.Visible = visibility; 
            app.SurnameEditField.Visible = visibility; 
            app.PersonalNumberEditField.Visible = visibility;
            app.DateofinfectionDatePicker.Visible = visibility; 
            app.NameEditFieldLabel.Visible = visibility; 
            app.SurnameEditFieldLabel.Visible = visibility; 
            app.PersonalNumberEditFieldLabel.Visible = visibility;
            app.DateofinfectionLabel.Visible = visibility; 
            app.SubmitaddpatientButton.Visible = visibility; 
        end
        
        function setDoctorVisibility(app, visibility)
            app.NameEditFieldDoctor.Visible = visibility;
            app.SurnameEditFieldDoctor.Visible = visibility;
            app.PersonalNumberEditFieldDoctor.Visible = visibility; 
            app.NameEditField_2Label.Visible = visibility;
            app.SurnameEditField_2Label.Visible = visibility;
            app.PersonalNumberEditField_2Label.Visible = visibility; 
            app.SubmitdoctorButton.Visible = visibility;
        end
        
        function setCuredVisibility(app, visibility)
             app.PersonalNumberDoctorEditField.Visible = visibility;
             app.PersonalNumberPatientEditField.Visible = visibility;
             app.PersonalNumberDoctorEditFieldLabel.Visible = visibility;
             app.PersonalNumberPatientEditFieldLabel.Visible = visibility;
             app.SubmitofcuredButton.Visible = visibility;
        end
        
        function setInfoIntervalVisibility(app, visibility)
            app.DatefromDatePicker.Visible = visibility;
            app.DatetoDatePicker.Visible = visibility;
            app.GetInfoButton.Visible = visibility;
        end
        
        function setInfoIntervalVisibilityInfo(app, visibility)
            app.InfoTextArea.Visible = visibility;
            app.InfoTextAreaLabel.Visible = visibility;
            app.SubmitinfoButton.Visible = visibility;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            app.covidManager = CovidManager();
            loadFile(app);
        end

        % Button pushed function: AddPatientButton
        function AddPatientButtonPushed(app, event)
           setPatientVisibility(app, true);
        end

        % Button pushed function: AddDoctorButton
        function addDoctorButtonPushed(app, event)
            setDoctorVisibility(app, true);
        end

        % Button pushed function: CurePatientButton
        function CurePatientButtonPushed(app, event)
               setCuredVisibility(app, true); 
        end

        % Button pushed function: InfoindateintervalButton
        function getInfectivityRate(app, event)
            setInfoIntervalVisibility(app, true);
        end

        % Button pushed function: SavetofileButton
        function SavetofileButtonPushed(app, event)
            save(app.covidManager);
        end

        % Button pushed function: LoadfromFileButton
        function loadFile(app, event)
            load(app.covidManager);
            showAll(app);
            updatePlotPatients(app);
            updatePlotDoctors(app);
        end

        % Button pushed function: SubmitaddpatientButton
        function submitAddPatient(app, event)
            name = app.NameEditField.Value;
            surname = app.SurnameEditField.Value;
            personalNumber = app.PersonalNumberEditField.Value;
            dateValue = app.DateofinfectionDatePicker.Value;
            if ~isValidInput(app, name, surname, personalNumber)
                return
            end
            if(dateValue > datetime('today') ||   isnat(app.DatefromDatePicker.Value))
               errordlg('Invalid date','Error'); 
               return
            end
            patient = Patient(name, surname, personalNumber, datetime(dateValue));
            if(addPatient(app.covidManager, patient))
                setPatientVisibility(app, false);
                showAll(app);
                updatePlotPatients(app);
                msgbox("Patient add");
            else 
                errordlg('ID is in database','Error');
            end
        end

        % Button pushed function: SubmitdoctorButton
        function submitAddDoctor(app, event)
            name = app.NameEditFieldDoctor.Value;
            surname = app.SurnameEditFieldDoctor.Value;
            personalNumber = app.PersonalNumberEditFieldDoctor.Value; 
            if ~isValidInput(app, name, surname, personalNumber)
                return
            end
            doctor = Doctor(name, surname, personalNumber);
            if(~addDoctor(app.covidManager, doctor))
               errordlg('ID is in database','Error');
            else
                showDoctors(app);   
                setDoctorVisibility(app, false);
                updatePlotDoctors(app);
                msgbox("Doctor added");
            end
        end

        % Button pushed function: SubmitofcuredButton
        function submitOfCured(app, event)
            personalNumberDoctor = app.PersonalNumberDoctorEditField.Value;
            personalNumberPatient = app.PersonalNumberPatientEditField.Value;
            doctor = getDoctorByPersonalNumber(app.covidManager, personalNumberDoctor);
            if(isempty(doctor))
                errordlg('ID of Doctor is not in database','Error');
                return
            end
            if(~setNegativePatient(app.covidManager, personalNumberPatient))
                errordlg('ID of Patient is not in database','Error');
                return
            end
            setCuredVisibility(app, false);
            addCuredPatient(doctor);
            showAll(app);
            updatePlotPatients(app);
            updatePlotDoctors(app);
        end

        % Button pushed function: GetInfoButton
        function getInfoByInterval(app, event)
            if isnat(app.DatefromDatePicker.Value) || isnat(app.DatetoDatePicker.Value)
               errordlg('Date was not selected','Error'); 
               return
            end
            setInfoIntervalVisibility(app, false);
            setInfoIntervalVisibilityInfo(app, true);
            syms f(x, y);
            f(x, y) = (x / y) * 100;
            a = length(getPatientsByDate(app.covidManager, app.DatefromDatePicker.Value, app.DatetoDatePicker.Value));
            b = length(app.covidManager.patients);
            equalsPatients = f(a, b);
            app.InfoTextArea.Value = "Infectivity rate in that days was " + double(equalsPatients) + " %" + newline;
        end

        % Button pushed function: SubmitinfoButton
        function submitInfoInterval(app, event)
            setInfoIntervalVisibilityInfo(app, false);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddDoctorButton = uibutton(app.UIFigure, 'push');
            app.AddDoctorButton.ButtonPushedFcn = createCallbackFcn(app, @addDoctorButtonPushed, true);
            app.AddDoctorButton.Text = 'Add Doctor';
            app.AddPatientButton = uibutton(app.UIFigure, 'push');
            app.AddPatientButton.ButtonPushedFcn = createCallbackFcn(app, @AddPatientButtonPushed, true);
            app.AddPatientButton.Text = 'Add Patient';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Visible = 'off';
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.Visible = 'off';
            app.SurnameEditFieldLabel = uilabel(app.UIFigure);
            app.SurnameEditFieldLabel.Visible = 'off';
            app.SurnameEditFieldLabel.Text = 'Surname';
            app.SurnameEditField = uieditfield(app.UIFigure, 'text');
            app.SurnameEditField.Visible = 'off';
            app.PersonalNumberEditFieldLabel = uilabel(app.UIFigure);
            app.PersonalNumberEditFieldLabel.Visible = 'off';
            app.PersonalNumberEditFieldLabel.Text = 'PersonalNumber';
            app.PersonalNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.PersonalNumberEditField.Visible = 'off';
            app.DateofinfectionLabel = uilabel(app.UIFigure);
            app.DateofinfectionLabel.Visible = 'off';
            app.DateofinfectionLabel.Text = 'Date of infection';
            app.DateofinfectionDatePicker = uidatepicker(app.UIFigure);
            app.DateofinfectionDatePicker.Visible = 'off';
            app.patientsTable = uitable(app.UIFigure);
            app.patientsTable.ColumnName = {'Name'; 'Surname'; 'PersonalNumber'; 'Date of infection'};
            app.patientsTable.RowName = {};
            app.doctorsTable = uitable(app.UIFigure);
            app.doctorsTable.ColumnName = {'Name'; 'Surname'; 'PersonalNumber'; 'Number of cured'; 'Bonus'};
            app.doctorsTable.RowName = {};
            app.PatientsLabel = uilabel(app.UIFigure);
            app.PatientsLabel.Text = 'Patients';
            app.DoctorsLabel = uilabel(app.UIFigure);
            app.DoctorsLabel.Text = 'Doctors';
            app.NameEditField_2Label = uilabel(app.UIFigure);
            app.NameEditField_2Label.Visible = 'off';
            app.NameEditField_2Label.Text = 'Name';
            app.NameEditFieldDoctor = uieditfield(app.UIFigure, 'text');
            app.NameEditFieldDoctor.Visible = 'off';
            app.SurnameEditField_2Label = uilabel(app.UIFigure);
            app.SurnameEditField_2Label.Visible = 'off';
            app.SurnameEditField_2Label.Text = 'Surname';
            app.SurnameEditFieldDoctor = uieditfield(app.UIFigure, 'text');
            app.SurnameEditFieldDoctor.Visible = 'off';
            app.PersonalNumberEditField_2Label = uilabel(app.UIFigure);
            app.PersonalNumberEditField_2Label.Visible = 'off';
            app.PersonalNumberEditField_2Label.Text = 'Personal Number';
            app.PersonalNumberEditFieldDoctor = uieditfield(app.UIFigure, 'numeric');
            app.PersonalNumberEditFieldDoctor.Visible = 'off';
            app.CurePatientButton = uibutton(app.UIFigure, 'push');
            app.CurePatientButton.ButtonPushedFcn = createCallbackFcn(app, @CurePatientButtonPushed, true);
            app.CurePatientButton.Text = 'Cure Patient';
            app.PersonalNumberDoctorEditFieldLabel = uilabel(app.UIFigure);
            app.PersonalNumberDoctorEditFieldLabel.Visible = 'off';
            app.PersonalNumberDoctorEditFieldLabel.Text = 'Personal Number Doctor';
            app.PersonalNumberDoctorEditField = uieditfield(app.UIFigure, 'numeric');
            app.PersonalNumberDoctorEditField.Visible = 'off';
            app.PersonalNumberPatientEditFieldLabel = uilabel(app.UIFigure);
            app.PersonalNumberPatientEditFieldLabel.Visible = 'off';
            app.PersonalNumberPatientEditFieldLabel.Text = 'Personal Number Patient';
            app.PersonalNumberPatientEditField = uieditfield(app.UIFigure, 'numeric');
            app.PersonalNumberPatientEditField.Visible = 'off';
            app.InfoindateintervalButton = uibutton(app.UIFigure, 'push');
            app.InfoindateintervalButton.ButtonPushedFcn = createCallbackFcn(app, @getInfectivityRate, true);
            app.InfoindateintervalButton.Text = 'Info in date interval';
            app.DatefromDatePickerLabel = uilabel(app.UIFigure);
            app.DatefromDatePickerLabel.Visible = 'off';
            app.DatefromDatePickerLabel.Text = 'Date from';
            app.DatefromDatePicker = uidatepicker(app.UIFigure);
            app.DatefromDatePicker.Visible = 'off';
            app.DatetoDatePickerLabel = uilabel(app.UIFigure);
            app.DatetoDatePickerLabel.Visible = 'off';
            app.DatetoDatePickerLabel.Text = 'Date to';
            app.DatetoDatePicker = uidatepicker(app.UIFigure);
            app.DatetoDatePicker.Visible = 'off';
            app.InfoTextAreaLabel = uilabel(app.UIFigure);
            app.InfoTextAreaLabel.Visible = 'off';
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.UIFigure);
            app.InfoTextArea.Visible = 'off';
            app.SavetofileButton = uibutton(app.UIFigure, 'push');
            app.SavetofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofileButtonPushed, true);
            app.SavetofileButton.Text = 'Save to file';
            app.LoadfromFileButton = uibutton(app.UIFigure, 'push');
            app.LoadfromFileButton.ButtonPushedFcn = createCallbackFcn(app, @loadFile, true);
            app.LoadfromFileButton.Text = 'Load from File';
            app.SubmitaddpatientButton = uibutton(app.UIFigure, 'push');
            app.SubmitaddpatientButton.ButtonPushedFcn = createCallbackFcn(app, @submitAddPatient, true);
            app.SubmitaddpatientButton.Visible = 'off';
            app.SubmitaddpatientButton.Text = 'Submit add patient';
            app.SubmitdoctorButton = uibutton(app.UIFigure, 'push');
            app.SubmitdoctorButton.ButtonPushedFcn = createCallbackFcn(app, @submitAddDoctor, true);
            app.SubmitdoctorButton.Visible = 'off';
            app.SubmitdoctorButton.Text = 'Submit doctor ';
            app.SubmitofcuredButton = uibutton(app.UIFigure, 'push');
            app.SubmitofcuredButton.ButtonPushedFcn = createCallbackFcn(app, @submitOfCured, true);
            app.SubmitofcuredButton.Visible = 'off';
            app.SubmitofcuredButton.Text = 'Submit of cured';
            app.GetInfoButton = uibutton(app.UIFigure, 'push');
            app.GetInfoButton.ButtonPushedFcn = createCallbackFcn(app, @getInfoByInterval, true);
            app.GetInfoButton.Visible = 'off';
            app.GetInfoButton.Text = 'Get Info';
            app.SubmitinfoButton = uibutton(app.UIFigure, 'push');
            app.SubmitinfoButton.ButtonPushedFcn = createCallbackFcn(app, @submitInfoInterval, true);
            app.SubmitinfoButton.Visible = 'off';
            app.SubmitinfoButton.Text = 'Submit info';
            app.plotPatients = uiaxes(app.UIFigure);
            title(app.plotPatients, 'Progress of covid')
            xlabel(app.plotPatients, 'Datum')
            ylabel(app.plotPatients, 'Pocet')
            zlabel(app.plotPatients, 'Z')
            app.plotDoctors = uiaxes(app.UIFigure);
            title(app.plotDoctors, {'Earnings'; ''})
            xlabel(app.plotDoctors, 'X')
            ylabel(app.plotDoctors, 'Y')
            zlabel(app.plotDoctors, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end